classdef glucolynx < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        USBLampLabel                 matlab.ui.control.Label
        USBLamp                      matlab.ui.control.Lamp
        BluetoothLampLabel           matlab.ui.control.Label
        BluetoothLamp                matlab.ui.control.Lamp
        BloodGlucoseLevelmmolLGaugeLabel  matlab.ui.control.Label
        BloodGlucoseLevelmmolLGauge  matlab.ui.control.LinearGauge
        ScanButton                   matlab.ui.control.Button
        Button                       matlab.ui.control.Button
        ShowResultButton             matlab.ui.control.Button
        BloodGlucoseLevelLabel       matlab.ui.control.Label
        BGL                          matlab.ui.control.Label
        mmolLLabel                   matlab.ui.control.Label
        CopyrightLabel               matlab.ui.control.Label
        BrowseButton                 matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            %imshow('glucolynx-logo-hor.png','Parent',app.UIAxes);
            %run (test);
            app.BloodGlucoseLevelLabel.Text = 'Status: Now doing x...';
        end

        % Button pushed function: ShowResultButton
        function ShowResultButtonPushed(app, event)
            app.BGL.Text = '5.5';
            app.BloodGlucoseLevelmmolLGauge.Value = 5.5;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)

        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            ee = app.BloodGlucoseLevelmmolLGauge.Value;
            assignin('base', 'c', ee);
            [filename pathname]=uigetfile({'*.csv'}, 'File Selector');
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.USBLampLabel = uilabel(app.UIFigure);
            app.USBLampLabel.Text = 'USB';
            app.USBLamp = uilamp(app.UIFigure);
            app.BluetoothLampLabel = uilabel(app.UIFigure);
            app.BluetoothLampLabel.Text = 'Bluetooth';
            app.BluetoothLamp = uilamp(app.UIFigure);
            app.BloodGlucoseLevelmmolLGaugeLabel = uilabel(app.UIFigure);
            app.BloodGlucoseLevelmmolLGaugeLabel.Text = 'Blood Glucose Level (mmol/L)';
            app.BloodGlucoseLevelmmolLGauge = uigauge(app.UIFigure, 'linear');
            app.BloodGlucoseLevelmmolLGauge.Limits = [0 30];
            app.BloodGlucoseLevelmmolLGauge.MajorTicks = [0 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30];
            app.ScanButton = uibutton(app.UIFigure, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'glucolynx-logo-hor.png';
            app.Button.Text = '';
            app.ShowResultButton = uibutton(app.UIFigure, 'push');
            app.ShowResultButton.ButtonPushedFcn = createCallbackFcn(app, @ShowResultButtonPushed, true);
            app.ShowResultButton.Text = 'Show Result';
            app.BloodGlucoseLevelLabel = uilabel(app.UIFigure);
            app.BloodGlucoseLevelLabel.Text = 'Blood Glucose Level';
            app.BGL = uilabel(app.UIFigure);
            app.BGL.Text = 'BGL';
            app.mmolLLabel = uilabel(app.UIFigure);
            app.mmolLLabel.Text = 'mmol/L';
            app.CopyrightLabel = uilabel(app.UIFigure);
            app.CopyrightLabel.Text = 'Copyright © 2019 by Thein Htut. All rights reserved.';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
        end
    end

    methods (Access = public)

        % Construct app
        function app = glucolynx

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end